classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        RunSimulationButton             matlab.ui.control.Button
        ParametersLabel                 matlab.ui.control.Label
        LoadParametersButton            matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        PositionTab                     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        OrientationTab                  matlab.ui.container.Tab
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        LinearVelocityTab               matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        AngularVelocityTab              matlab.ui.container.Tab
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        AllActuatorsTab                 matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        TrajectoryTab                   matlab.ui.container.Tab
        UIAxes14                        matlab.ui.control.UIAxes
        ErrorWP0WP1Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP0WP1EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP0WP1EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP0WP1EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP0WP1EditFieldLabel  matlab.ui.control.Label
        UIAxes15                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
           
            table = readtable("data.xlsx");
            app.UITable.Data = table;
            app.UITable.ColumnName = table.Properties.VariableNames;

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

            mdl = 'RoboticFish_NGC';
            open_system(mdl);

            % Save table in data
            data = app.UITable.Data;

            % Set parameters
            for i = 1:size(data, 2)
                paramName=app.UITable.ColumnName(i);
                paramValue = data{1, i}; % Nuovo valore del parametri
                if(i<2)
                    set_param('RoboticFish_NGC', string(paramName), string(paramValue))
                elseif(i<7)
                    set_param('RoboticFish_NGC/Target Position', string(paramName), string(paramValue));
                else
                    set_param('RoboticFish_NGC/Robotic-Fish Model & PropulsionSystem', string(paramName), string(paramValue));
                end
            end

            % Save model
            save_system(mdl);

            % Run simulation
            simOut=sim(mdl);

            % Squeeze variables
            simOut.X=squeeze(simOut.X);
            simOut.Y=squeeze(simOut.Y);
            simOut.Z=squeeze(simOut.Z);
            simOut.Roll=squeeze(simOut.Roll);
            simOut.Pitch=squeeze(simOut.Pitch);
            simOut.Yaw=squeeze(simOut.Yaw);
            simOut.u=squeeze(simOut.u);
            simOut.v=squeeze(simOut.v);
            simOut.w=squeeze(simOut.w);
            simOut.p=squeeze(simOut.p);
            simOut.q=squeeze(simOut.q);
            simOut.r=squeeze(simOut.r);

            % Save time
            time= simOut.tout;
            dt = simOut.SimulationMetadata.ModelInfo.SolverInfo.FixedStepSize;
            duration = simOut.SimulationMetadata.ModelInfo.StopTime;

            % Plot
            data1=[simOut.X, simOut.Xr];
            plot(app.UIAxes,time,data1);
            legend(app.UIAxes,'X','Xr');

            data2=[simOut.Y, simOut.Yr];
            plot(app.UIAxes_2,time,data2);
            legend(app.UIAxes_2,'Y','Yr');

            data3=[simOut.Z, simOut.Zr];
            plot(app.UIAxes_3,time,data3);
            legend(app.UIAxes_3,'Z','Zr');

            plot(app.UIAxes_4,time,simOut.Roll);

            plot(app.UIAxes_5,time,simOut.Pitch);

            plot(app.UIAxes_6,time,simOut.Yaw);

            plot(app.UIAxes_7,time,simOut.u);

            plot(app.UIAxes_8,time,simOut.v);

            plot(app.UIAxes_9,time,simOut.w);

            plot(app.UIAxes_10,time,simOut.p);

            plot(app.UIAxes_11,time,simOut.q);

            plot(app.UIAxes_12,time,simOut.r);

            data4=[simOut.f_HT3, simOut.Thetastr_HT3, simOut.uHT1, simOut.uHT2, simOut.uVT1, simOut.uVT2, simOut.uVT3];
            plot(app.UIAxes13 ,time, data4);
            legend(app.UIAxes13,'f_HT3 [Hz]','Thetastr_HT3 [rad]','uHT1','uHT2','uVT1','uVT2','uVT3')

            % Plot trajectory
            data5=[simOut.Xr, simOut.X];
            data6=[simOut.Yr, simOut.Y];
            plot(app.UIAxes14,data5,data6);
            WP_x = [0 10 15 20 20 10 10 0];
            WP_y = [0 0 5 0 -10 -10 0 0];
            hold(app.UIAxes14);
            plot(app.UIAxes14,WP_x,WP_y,'go');
            legend(app.UIAxes14,'Reference Trajectory','Effective Trajectory','Waypoints');
            str = {'WP0','WP1','WP2','WP3','WP4','WP5','WP6','WP7'};
            txt_x = [0.2 10.2 15.2 20.2 20.2 10.2 10.2 0.2];
            txt_y = [0.2 0.2 5.2 0.2 -10.2 -10.2 -0.2 -0.2];
            text(app.UIAxes14,txt_x,txt_y,str)

            % Error computation WP0-WP1
            WP0 = [0 0];
            WP1 = [9.7 0];
            timeWP0=1;
            timeWP1=floor(10.33/dt);
            Delta_t01 = timeWP1-timeWP0+1;
            XrWP0WP1 = 1:Delta_t01;
            YrWP0WP1 = 1:Delta_t01;
            distance_WP0WP1 = 1:Delta_t01;
            Lreal = 0;
            Dreal = 1:Delta_t01;
            Dref = 1:Delta_t01;
            DWP = WP1-WP0;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t01
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP0WP1(1) = WP0(1)+cos(psi)*Dref(1);
            YrWP0WP1(1) = WP0(2)+sin(psi)*Dref(1);
            distance_WP0WP1(1) = sqrt((simOut.X(1)-XrWP0WP1(1))^2+(simOut.Y(1)-YrWP0WP1(1))^2);
            for i=2:Delta_t01
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP0WP1(i) = XrWP0WP1(i-1)+cos(psi)*Dref(i);
                YrWP0WP1(i) = YrWP0WP1(i-1)+sin(psi)*Dref(i);
                distance_WP0WP1(i) = sqrt((simOut.X(i)-XrWP0WP1(i))^2+(simOut.Y(i)-YrWP0WP1(i))^2);
            end

            app.AverageDistanceWP0WP1EditField.Value = mean(distance_WP0WP1);
            app.StandardDeviationDistanceWP0WP1EditField.Value = sqrt(sum(distance_WP0WP1.^2)/Delta_t01);

            % Plot WP0-WP1
            data01x=[XrWP0WP1.', simOut.X(timeWP0:timeWP1)];
            data01y=[YrWP0WP1.', simOut.Y(timeWP0:timeWP1)];
            plot(app.UIAxes15,data01x,data01y);
            hold(app.UIAxes15);
            WPx=[WP0(1) WP1(1)];
            WPy=[WP0(2) WP1(2)];
            plot(app.UIAxes15,WPx,WPy,'go');
            legend(app.UIAxes15,'Reference Trajectory','Effective Trajectory','Waypoints');
            str01 = {'WP0','WP1'};
            txt_x01 = [0.2 9.9];
            txt_y01 = [0.2 0.2];
            text(app.UIAxes15,txt_x01,txt_y01,str01)


            % Plot fin kinematics
            cd 'Fin Kinematics'
            main
            cd ..

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UITable = uitable(app.MainTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LoadParametersButton = uibutton(app.MainTab, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ParametersLabel = uilabel(app.MainTab);
            app.ParametersLabel.Text = 'Parameters';
            app.RunSimulationButton = uibutton(app.MainTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.UIAxes_3 = uiaxes(app.PositionTab);
            title(app.UIAxes_3, 'Z ')
            xlabel(app.UIAxes_3, 'Time [s]')
            ylabel(app.UIAxes_3, 'Z [m]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.PositionTab);
            title(app.UIAxes_2, 'Y')
            xlabel(app.UIAxes_2, 'Time [s]')
            ylabel(app.UIAxes_2, 'Y [m]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.PositionTab);
            title(app.UIAxes, 'X')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'X [m]')
            zlabel(app.UIAxes, 'Z')
            app.OrientationTab = uitab(app.TabGroup);
            app.OrientationTab.Title = 'Orientation';
            app.UIAxes_6 = uiaxes(app.OrientationTab);
            title(app.UIAxes_6, 'Yaw')
            xlabel(app.UIAxes_6, 'Time [s]')
            ylabel(app.UIAxes_6, 'Yaw [rad]')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_5 = uiaxes(app.OrientationTab);
            title(app.UIAxes_5, 'Pitch')
            xlabel(app.UIAxes_5, 'Time [s]')
            ylabel(app.UIAxes_5, 'Pitch [rad]')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_4 = uiaxes(app.OrientationTab);
            title(app.UIAxes_4, 'Roll')
            xlabel(app.UIAxes_4, 'Time [s]')
            ylabel(app.UIAxes_4, 'Roll [rad]')
            zlabel(app.UIAxes_4, 'Z')
            app.LinearVelocityTab = uitab(app.TabGroup);
            app.LinearVelocityTab.Title = 'Linear Velocity';
            app.UIAxes_9 = uiaxes(app.LinearVelocityTab);
            title(app.UIAxes_9, 'w')
            xlabel(app.UIAxes_9, 'Time [s]')
            ylabel(app.UIAxes_9, 'w [m/s]')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_8 = uiaxes(app.LinearVelocityTab);
            title(app.UIAxes_8, 'v')
            xlabel(app.UIAxes_8, 'Time [s]')
            ylabel(app.UIAxes_8, 'v [m/s]')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_7 = uiaxes(app.LinearVelocityTab);
            title(app.UIAxes_7, 'u')
            xlabel(app.UIAxes_7, 'Time [s]')
            ylabel(app.UIAxes_7, 'u [m/s]')
            zlabel(app.UIAxes_7, 'Z')
            app.AngularVelocityTab = uitab(app.TabGroup);
            app.AngularVelocityTab.Title = 'Angular Velocity';
            app.UIAxes_12 = uiaxes(app.AngularVelocityTab);
            title(app.UIAxes_12, 'r')
            xlabel(app.UIAxes_12, 'Time [s]')
            ylabel(app.UIAxes_12, 'r [rad/s]')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_11 = uiaxes(app.AngularVelocityTab);
            title(app.UIAxes_11, 'q')
            xlabel(app.UIAxes_11, 'Time [s]')
            ylabel(app.UIAxes_11, 'q [rad/s]')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_10 = uiaxes(app.AngularVelocityTab);
            title(app.UIAxes_10, 'p')
            xlabel(app.UIAxes_10, 'Time [s]')
            ylabel(app.UIAxes_10, 'p [rad/s]')
            zlabel(app.UIAxes_10, 'Z')
            app.AllActuatorsTab = uitab(app.TabGroup);
            app.AllActuatorsTab.Title = 'All Actuators';
            app.UIAxes13 = uiaxes(app.AllActuatorsTab);
            title(app.UIAxes13, 'All Actuators')
            xlabel(app.UIAxes13, 'Time [s]')
            zlabel(app.UIAxes13, 'Z')
            app.TrajectoryTab = uitab(app.TabGroup);
            app.TrajectoryTab.Title = 'Trajectory';
            app.UIAxes14 = uiaxes(app.TrajectoryTab);
            title(app.UIAxes14, 'Effective trajectory with respect to reference trajectory')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.ErrorWP0WP1Tab = uitab(app.TabGroup);
            app.ErrorWP0WP1Tab.Title = 'Error WP0-WP1';
            app.UIAxes15 = uiaxes(app.ErrorWP0WP1Tab);
            title(app.UIAxes15, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            zlabel(app.UIAxes15, 'Z')
            app.AverageDistanceWP0WP1EditFieldLabel = uilabel(app.ErrorWP0WP1Tab);
            app.AverageDistanceWP0WP1EditFieldLabel.Text = '  Average Distance WP0-WP1';
            app.AverageDistanceWP0WP1EditField = uieditfield(app.ErrorWP0WP1Tab, 'numeric');
            app.AverageDistanceWP0WP1EditField.Editable = 'off';
            app.StandardDeviationDistanceWP0WP1EditFieldLabel = uilabel(app.ErrorWP0WP1Tab);
            app.StandardDeviationDistanceWP0WP1EditFieldLabel.Text = 'Standard Deviation Distance WP0-WP1';
            app.StandardDeviationDistanceWP0WP1EditField = uieditfield(app.ErrorWP0WP1Tab, 'numeric');
            app.StandardDeviationDistanceWP0WP1EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end